classdef ModelManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CreateRepositoryButton       matlab.ui.control.Button
        LabelEditField               matlab.ui.control.Label
        repositoryDirField           matlab.ui.control.EditField
        SelectRepositoryDirButton    matlab.ui.control.Button
        LabelEditField2              matlab.ui.control.Label
        CurrentVersionField          matlab.ui.control.EditField
        Panel                        matlab.ui.container.Panel
        CreateNewModelVersionButton  matlab.ui.control.Button
        SaveModelCheckpointButton    matlab.ui.control.Button
        LoadSavedModelButton         matlab.ui.control.Button
        CompareModelsButton          matlab.ui.control.Button
        ExportmodelsButton           matlab.ui.control.Button
        ImportModelsButton           matlab.ui.control.Button
        ShowGitOutputCheckBox        matlab.ui.control.CheckBox
        ShownotesButton              matlab.ui.control.Button
        ShowallcommitsCheckBox       matlab.ui.control.CheckBox
        ModeldetailsPanel            matlab.ui.container.Panel
        CommitMessageTextArea        matlab.ui.control.TextArea
        CommittedmodelsinrepositoryPanel  matlab.ui.container.Panel
        ArchivedModelListBox         matlab.ui.control.ListBox
    end


    properties (Access = private)
        repositoryDir % working directory for repository
        gitRepoFlag % indicates if repository directory is valid
        newModelVersionFlag = false % indicates if a new model version was created without comitting it yet
        currentModelVersion % Description
        detachedHead = false % set to true if checking out (and modifying) a previous model version
        diffFunctions % Description
        showAllCommits = false
    end

    methods (Access = private)
        
        function results = updateRepositoryDir(app, newDir)
             % set repo directory to cwd
            app.repositoryDir = newDir;
            % update text field
            set(app.repositoryDirField, 'Value', app.repositoryDir);
            % check for repository
            gitStatus = gitCwd(app, 'status');
            if ~isempty(strfind(gitStatus, 'Not a git repository'))
                app.gitRepoFlag = false;
                set(app.repositoryDirField, 'FontColor', 'red');
                set(app.Panel.Children, 'Enable', 'off');
                set(app.CommitMessageTextArea, 'Value', '');
                set(app.ArchivedModelListBox, 'Items', {});
                set(app.CurrentVersionField, 'Value', '');
                return
            end
            set(app.Panel.Children, 'Enable', 'on');

            app.gitRepoFlag = true;
            app.refreshArchivedModelList();
            
            % get the current model version if it has a tag
            tmp = strcat(strtrim(gitCwd(app, 'log --tags --pretty="%h%d" --simplify-by-decoration')));
            tmp2 = regexp(tmp, '(?<shah>\w+) \([\w->\s,/]*tag: (?<tag>[\w.-]+)', 'names');          
            tags = {tmp2.tag};
            shas = {tmp2.shah};
            
            currentSha = strtrim(gitCwd(app, 'rev-parse --short HEAD'));
            [H,ix] = ismember(currentSha,shas); % HEAD is tagged
            if H
                setCurrentModelVersion(app, tags{ix});           
            else
                set(app.CurrentVersionField, 'Value', [currentSha ' -- not tagged']);    
            end
            
            % get any diff functions that should be applied
            if exist(fullfile(app.repositoryDir, '.diff_config'), 'file')
                f = fopen(fullfile(app.repositoryDir, '.diff_config'), 'r');
                tmp = textscan(f, '%s', 'Delimiter', '\n');
                tmp = cellfun(@(s) strsplit(s,' '), tmp{1}, 'UniformOutput', false);
%                 app.diffFunctions = textscan(f, '%s %s');
                app.diffFunctions = tmp;
                fclose(f);
            end
            
        end

        function results = gitCwd(app, varargin)
            results = git([sprintf('-C "%s" ', app.repositoryDir), varargin{:}]);
        end

        function results = setCurrentModelVersion(app, version)
            if isempty(version)
                set(app.CommitMessageTextArea, 'Value', '')
                return
            end
            
            app.currentModelVersion = version;
            set(app.CurrentVersionField, 'Value', version);    
            set(app.ArchivedModelListBox, 'Value', {version});
            updateCommitMessageText(app, version);
        end


        function success = saveModelCheckpoint(app)
            % commit the current model and move the tag
            
            % get files to commit
%             [FileName, PathName] = uigetfile({'*.sbproj;*.xlsx';'*.*'}, 'Select files to save', ...
%                 [app.repositoryDir '/*.sbproj'], 'MultiSelect', 'on');
                
            workTree = strtrim(gitCwd(app, 'rev-parse --show-toplevel'));
            changedFiles = gitCwd(app, 'status --porcelain -uno');            
            changedFiles = strsplit(changedFiles,'\n');
            changedFiles = changedFiles(cellfun(@(X) strncmp(strtrim(X),'M',1), changedFiles));
            changedFiles = cellfun(@(X) regexp(X, 'M\s+(.*)', 'tokens'), changedFiles);
            if ~isempty(changedFiles)
                initFiles = [changedFiles{:}];
            else
                initFiles = {};
            end
            FileName = uipickfiles('FilterSpec', fullfile(app.repositoryDir, '*.sbproj'), ...
                 'Type',{'*.*','All files'; '*.sbproj', 'Model files'; '*.xlsx', 'xlsx files'; '*.m', 'Matlab scripts'}, ...
                 'Prompt', 'Select files to save', 'Output', 'cell', ...
                 'Append', cellfun(@(X) fullfile(workTree, X), initFiles, 'UniformOutput', false));
                
                
            if (isnumeric(FileName) && FileName == 0) || isempty(FileName)
                success = false;
                return
            end
            
            if ischar(FileName)
                FileName = {FileName};
            end
            
            
            % before staging files create a new branch if necessary
            % only applies if in detached Head mode
            if app.detachedHead
                result = gitCwd(app, ['checkout -b b_' app.currentModelVersion]);
                if ~isempty(strfind(result, 'already exists'))
                    % already branched before
                    gitCwd(app, ['checkout b_' app.currentModelVersion])
                end
                app.detachedHead = false;
            end
            
            % get commit message
            for k=1:length(FileName)
                gitCwd(app, sprintf('add "%s" ', FileName{k}));
            end
            
            % get the diff to HEAD as default commit msg
            summary = doModelComparison(app, {app.currentModelVersion}, true);
            
            
            commitMsg = inputdlg('Enter commit message', 'Commit message', [10,100], {summary});
            if isempty(commitMsg)
                errordlg('No message entered, not committing!')
                gitCwd(app, 'reset')
                success = false;
                return
            end
            
            if isunix 
                ending = '\n';
            else
                ending = '\r\n';
            end
            commitMsg = strjoin(arrayfun( @(k) strtrim( commitMsg{1}(k,:)), 1:size(commitMsg{1},1), 'UniformOutput', false), ending);


            if app.newModelVersionFlag
                % commit & tag

                gitCwd(app, sprintf('commit -m "%s"', commitMsg));
                gitCwd(app, ['tag -f ', app.currentModelVersion]);
                
                % add sha for base commit
                baseCommit = strtrim(gitCwd(app, 'show -s --pretty="%H"'));
%                 gitCwd(app, sprintf('notes add -m ''%s''', baseCommit));
                fHandle = fopen(fullfile(app.repositoryDir, '.baseSha1'), 'a');
                fprintf(fHandle, '%s\t%s\n', app.currentModelVersion, baseCommit);
                fclose(fHandle);
                
            else
                % check for existing base commit note

%                 if exist(fullfile(app.repositoryDir, '.baseSha1'), 'file')
%                     fHandle = fopen(fullfile(app.repositoryDir, '.baseSha1'), 'r');                    
%                     scanStr = textscan(fHandle, '%s\t%s');
%                     fclose(fHandle);
%                 end
%                 tags = scanStr(:,1);
%                 shas = scanStr(:,2);
%                 baseCommit = shas{1}{find(strcmp(app.currentModelVersion, [tags{:}]))};
%                     
                % commit & tag
                currentSha = strtrim(gitCwd(app, 'show -s --pretty="%H"'));
                if ~isunix
                    tmpf = tempname;
                    fh = fopen(tmpf,'w');
                    fprintf(fh, '%s', commitMsg);
                    fclose(fh);
                    gitCwd(app, sprintf('commit -F %s', tmpf));
                else
                    gitCwd(app, sprintf('commit -m "%s"', commitMsg));         
                end
                gitCwd(app, ['tag -f ', app.currentModelVersion]);
                gitCwd(app, sprintf('notes copy %s %s', currentSha, app.currentModelVersion));
%                 gitCwd(app, sprintf('notes add -m ''%s''', baseCommit));
%                 newSha = strtrim(gitCwd(app, 'show -s --pretty="%H"'));
%                 fHandle = fopen(fullfile(app.repositoryDir, '.baseSha1'), 'a');                    
%                 fprintf(fHandle, '%s\t%s\n', app.currentModelVersion, newSha);
%                 fclose(fHandle);
            end
               
            % update the oneline tag in the model list
            app.refreshArchivedModelList();
            app.newModelVersionFlag = false;
            ArchivedModelListBoxValueChanged(app)
            success = true;
        end
        
        function results = refreshArchivedModelList(app)
                  
            tmp = strcat(strtrim(gitCwd(app, 'log --tags --pretty="%h%d" --simplify-by-decoration')));
            tmp2 = regexp(tmp, '(?<shah>\w+) \([\w->\s,/]*tag: (?<tag>[\w.-]+)', 'names');          
            tags = {tmp2.tag};
            shas = {tmp2.shah};
            
            
            if app.showAllCommits
                commits = strsplit(strtrim(gitCwd(app, 'log --pretty=%h')));
                itemsData = cell(1, length(commits));
                name=cell(1,length(commits));
                for k=1:length(commits)
                    [h,ix] = ismember(commits{k}, shas);
                    if h
                        name{k} = tags{ix};
                    else
                        name{k} = commits{k};
                    end
                    itemsData{k} = name{k};
                end                
            else
                commits = tags;
                name = tags;
                itemsData = tags;
            end                

           
            if ~isempty(name)
                lines = strsplit(strtrim(gitCwd(app, ['show -s --pretty=%s ' strjoin(commits), ' --'])), '\n')';
                modelOnelines = strcat(name', repmat({' - '},size(name')), lines);
            else
                modelOnelines = {};
            end
%           modelOnelines{k} = sprintf('%s - %s', name, gitCwd(app, ['show -s --pretty=''%s'' ' commits{k} ' -- ']));

            
            set(app.repositoryDirField, 'FontColor', 'black');
            set(app.ArchivedModelListBox, 'Items', modelOnelines, 'ItemsData', itemsData);
        
%             if isempty(commits)
%                 app.setCurrentModelVersion('');
%                 return
%             end
%             setCurrentModelVersion(app, commits{1});
            
        end
        
        function results = updateCommitMessageText(app, value)
            % update commit message text area
            if app.newModelVersionFlag && strcmp(value, app.currentModelVersion)
                % no commit msg yet
                set(app.CommitMessageTextArea, 'Value', 'model not yet saved');
                return
            end            
           
            if exist(fullfile(app.repositoryDir, '.baseSha1'), 'file')
                fHandle = fopen(fullfile(app.repositoryDir, '.baseSha1'), 'r');                    
                scanStr = textscan(fHandle, '%s\t%s');
                fclose(fHandle);
                tags = scanStr(:,1);
                shas = scanStr(:,2);
                commitIdx = find(strcmp(value, [tags{:}]));
                if isempty(commitIdx)
                    baseCommit = value;
                else
                    baseCommit = shas{1}{commitIdx};
                end
            else
                baseCommit = value;
            end
            
            commitMsg = [gitCwd(app, ['show -s --pretty=%ci%n%n%s%n%n%b%n ', baseCommit])];

            thisSha = strtrim(gitCwd(app, ['rev-parse ' value]));
            if ~strcmp(baseCommit, thisSha)
                baseCommit = [baseCommit '..'];
                commitMsg = [gitCwd(app, ['show -s --pretty=%ci%n%n%s%n%n%b%n ', baseCommit, value]), commitMsg];
            end
            set(app.CommitMessageTextArea, 'Value', commitMsg);
        end
        
        function summary = doModelComparison(app, items, silent)
            % compare two models 
                        
            summary = '';
            
            % select sbproj files to compare
            
            for fileId=1:2
                if fileId==2 && length(items)==1
                    commitFilesStr = gitCwd(app, 'ls-files --full-name ');
                else
                    commitFilesStr = gitCwd(app, ['ls-tree --name-only --full-name -r ' items{fileId}]);
                end
                filePaths = strsplit(commitFilesStr, '\n');
                modelItems{fileId} = {};
                fullPaths{fileId} = {};
                
                diffFiles{fileId} = {}; % files for custom comparison 
                
                for k=1:length(filePaths)
                    [modelDir,fileName,ext] = fileparts(filePaths{k});
                    if strcmp(ext,'.sbproj')
                        modelItems{fileId} = [modelItems{fileId} fileName];
            %                         modelItems{fileId} = [modelItems{fileId} filePaths{k}];                        
                        fullPaths{fileId} = [fullPaths{fileId} filePaths{k}];
                    else 
                        if isempty(app.diffFunctions), continue, end
                        
                        for l=1:length(app.diffFunctions)
                            if strcmp(filePaths{k}, app.diffFunctions{l}{1})
                                diffFiles{fileId}{l} = filePaths{k};
                            end
                        end
                    end
                    
                end
            end
            
            
            % make user select sbproj files to compare
            compareModels = false;
            if length(modelItems{1}) > 1 || length(modelItems{2}) > 1
                
                obj = ModelComparisonDialog;
                set(obj.VersionNumber1, 'Value', items{1});
                if length(items)==1
                    set(obj.VersionNumber2, 'Value', 'working dir'); 
                else
                    set(obj.VersionNumber2, 'Value', items{2}); 
                end
                
            %                 obj.updateModelVersionLists(modelItems{1}, modelItems{2})
                obj.updateModelVersionLists(fullPaths{1}, fullPaths{2})
    
                
                waitfor(obj, 'Status')
                if ~strcmp(obj.Status, 'submit')
                    delete(obj)
                    return
                end
                
                val1 = get(obj.ModelListBox1, 'Value');
                val2 = get(obj.ModelListBox2, 'Value');
                
            %                 modelIdx1 = find(strcmp(modelItems{1}, name1));
            %                 modelIdx2 = find(strcmp(modelItems{2}, name2));
                modelIdx1 = find(strcmp(fullPaths{1}, val1));
                modelIdx2 = find(strcmp(fullPaths{2}, val2));  
                name1 = modelItems{1}{modelIdx1};
                name2 = modelItems{2}{modelIdx2};
                
                delete(obj)
                compareModels = true;
            elseif length(modelItems{1})==1 && length(modelItems{2})==1
                name1 = modelItems{1}{1};
                name2 = modelItems{2}{1};
          
                modelIdx1 = 1;
                modelIdx2 = 1;
                compareModels = true;
            end
                
            if compareModels
                    
                tmp_1 = tempname;
                tmp_2 = tempname;
                item1 = items{1};
                
                gitCwd(app, sprintf('show %s:%s > %s.sbproj', items{1}, fullPaths{1}{modelIdx1}, tmp_1));
                if length(items) == 1
                    workingTree = strtrim(gitCwd(app, 'rev-parse --show-toplevel'));
                    copyfile(fullfile(workingTree, fullPaths{2}{modelIdx2}), [tmp_2 '.sbproj']);
%                     item2 = item1;
%                     item1 = 'working';
                    item2 = 'working';
                else
                    gitCwd(app, sprintf('show %s:%s > %s.sbproj', items{2}, fullPaths{2}{modelIdx2}, tmp_2));
                    item2 = items{2};
                end
                %             tmpfile = tempname;
                m1 = sbioloadproject(sprintf('%s.sbproj', tmp_1));
                m2 = sbioloadproject(sprintf('%s.sbproj', tmp_2));
                m1 = m1.m1;
                m2 = m2.m1;
                
                summary = sbprojDiff(m1, m2, item1, item2);
            
            end
            
            %% apply custom diff functions           
            for k=1:length(diffFiles{1})
                
                [~,pName,pExt] = fileparts(app.diffFunctions{k}{1});
                tmpFile1 = [tempname,pExt];
                tmpFile2 = [tempname,pExt];
                if isempty(diffFiles{1}{k}), continue, end
                gitCwd(app, sprintf('show %s:%s > %s', items{1}, diffFiles{1}{k}, tmpFile1 ));
                if length(items) == 1
                    workingTree = strtrim(gitCwd(app, 'rev-parse --show-toplevel'));
                    copyfile(fullfile(workingTree, diffFiles{2}{k}), tmpFile2);
            %                     items{2} = 'working';
                else
                    if isempty(diffFiles{2}{k}), continue, end
                    gitCwd(app, sprintf('show %s:%s > %s', items{2}, diffFiles{2}{k}, tmpFile2 ));
                end    
            
                diffFun = str2func(app.diffFunctions{k}{2});
                try                    
                    summary = [summary, diffFun(tmpFile2,tmpFile1,item2,item1, app.diffFunctions{k}{3:end})];
                catch diffErr
                    warning('Error encountered running custom diff function %s\n', app.diffFunctions{k}{2});
                    disp(diffErr)
                end
            end
            
            if ~silent
                fprintf('%s SUMMARY %s\n', repmat('%',40,1), repmat('%',40,1))
                disp(summary)            
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global GIT_SHOW_OUTPUT
            GIT_SHOW_OUTPUT = false;
            app.updateRepositoryDir(pwd);
           
                    
        end

        % Value changed function: ArchivedModelListBox
        function ArchivedModelListBoxValueChanged(app, event)
            value = app.ArchivedModelListBox.Value;
            if length(value) == 1
                value = value{1};
            else
                return
            end
            % model version selected           
            updateCommitMessageText(app, value);
        end

        % Button pushed function: CompareModelsButton
        function CompareModelsButtonButtonPushed(app, event)
            
                        
            % check that exactly two models versions are selected
            items = get(app.ArchivedModelListBox, 'Value');
            if ~iscell(items)
                items = {items};
            end
            
            if length(items) > 2
                errordlg('Too many models selected for comparison')
                return
            end
            
            doModelComparison(app, items, false);


        end

        % Button pushed function: CreateNewModelVersionButton
        function CreateNewModelVersionButtonButtonPushed(app, event)
            % check that a new model isn't already created but unsaved
                                    
            if app.newModelVersionFlag
                saveChoice = questdlg('Model changes are unsaved. Save to current model or to new model version?', ...
                    'Save model changes', 'Save to current model', 'Save to new model', 'Cancel', 'Save to current model');
                if strcmp(saveChoice, 'Cancel')
                    return
                elseif strcmp(saveChoice, 'Save to current model')
                    saveModelCheckpoint(app)
                end                                        
            end
            % launch dialog for creating new model version
            modelName = inputdlg(sprintf('%200s', 'Model version'), 'Enter model version');
            if isempty(modelName) % cancelled
                return
            end
            
            % validate the name
            reName = regexp(modelName, '^(?!/|.*([/.]\.|//|@\{|\\\\))[^\040\177 ~^:?*\[]+(?<!\.lock|[/.])$');
            if isempty(reName{1})
                errordlg('Invalid version name. Run "git help check-ref-format" for valid name details')
                return
            end
            
            % get existing names
            modelItems = get(app.ArchivedModelListBox, 'Items');
            modelItemsData = get(app.ArchivedModelListBox, 'ItemsData');
            
            % check for uniqueness
            if isempty(modelName)
                return
            elseif any(strcmp(modelItems, modelName))
                errordlg('Model version must be unique');
                return
            end
            
            % create new model version
            % just add it to the list for now, don't commit it
            modelItems = [modelItems, modelName];
            modelItemsData = [modelItemsData, modelName];
            app.newModelVersionFlag = true;
            set(app.ArchivedModelListBox, 'Items', modelItems, 'ItemsData', modelItemsData)
            set(app.ArchivedModelListBox, 'Value', modelName(1));
            
            app.setCurrentModelVersion(modelName{1});
            
            
            % update commit message box text
            set(app.CommitMessageTextArea, 'Value', 'model not yet saved');
            
        end

        % Button pushed function: CreateRepositoryButton
        function CreateRepositoryButtonPushed(app, event)
            % check if a repository already exists in this directory
            if ~exist('.git', 'dir')
                result = gitCwd(app, 'init .');
                if isempty(strfind(result, 'Initialized empty Git repository'))
                    errordlg('Could not initialize git repository');
                    return
                end
                
                updateRepositoryDir(app, app.repositoryDir);
                set(app.CommitMessageTextArea, 'Value', '');
            
            else
                errordlg('Git repository already initialized');
            end 
                       
        end

        % Button pushed function: ExportmodelsButton
        function ExportmodelsButtonButtonPushed(app, event)
            % make user select directory to the models
            values = get(app.ArchivedModelListBox, 'Value');
            if isempty(values)
                errordlg('Must select model(s) before exporting', 'Error');
                return
            end
            
            exportDir = uigetdir(app.repositoryDir, 'Select export directory');
            if ~exportDir
                return
            end
            
            for k=1:length(values)
                fileStr = gitCwd(app, sprintf('show --pretty="" --name-only -r %s', values{k}));
                fileNames = strsplit(fileStr,'\n');
                fileNames = fileNames(1:end-1);
                
                for l=1:length(fileNames)
                    [thisFileDir, thisFileName, thisExt] = fileparts(fileNames{l});
                    fullDir = fullfile(exportDir, values{k}, thisFileDir);
                    if ~exist(fullDir,'dir')
                        mkdir(fullDir);
                    end
                    gitCwd(app, sprintf('show %s:%s > "%s"', values{k}, fileNames{l}, fullfile(fullDir, [thisFileName, thisExt])))
                end
            end


        end

        % Button pushed function: ImportModelsButton
        function ImportModelsButtonPushed(app, event)
            % import files into the workspace for the selected model
            
            % confirm overwrite of the selected model
            selectedModel = get(app.ArchivedModelListBox, 'Value');
            if length(selectedModel) ~= 1
                errordlg('Must select a single model version to import into');
                return
            end
            
            result = questdlg(sprintf('Modify model version %s?', selectedModel{1}), 'Confirm import model', ...
                'Yes', 'No', 'No');
            
            if strcmp(result, 'No')    
                return
            end
            
            % stash workspace
            gitCwd(app, 'stash');
            % save current version
            currentVersion = app.currentModelVersion;
            newVersion = app.newModelVersionFlag;            % save new version flag
            
            % switch to selected version
            app.currentModelVersion = selectedModel{1};
            app.detachedHead = true;
            app.newModelVersionFlag = false;
            
            gitCwd(app, ['checkout ', selectedModel{1}]);
            
            saveModelCheckpoint(app); % will checkout the correct branch first
            
            % restore
            
            masterCommit = gitCwd(app, 'rev-parse master');
            currentCommit = gitCwd(app, ['rev-parse ' currentVersion]);
            if ~strcmp(masterCommit, currentCommit)
                app.detachedHead = true;
                gitCwd(app, ['checkout -f ' currentVersion]);
            else 
                gitCwd(app, ['checkout -f master']);
            end
            app.setCurrentModelVersion(currentVersion);
            
            gitCwd(app, 'stash pop');
            app.newModelVersionFlag = newVersion;
            
            
        end

        % Button pushed function: LoadSavedModelButton
        function LoadSavedModelButtonButtonPushed(app, event)
            selectedModelVersion = get(app.ArchivedModelListBox, 'Value');
            if isempty(selectedModelVersion)
                errordlg('Please select a model version')
                return
            end
            
            if length(selectedModelVersion) > 1
                errordlg('Must select only a single model')
                return
            end
            
            selectedModelVersion = selectedModelVersion{1};
            
            % checkout a previous version of the model
            gitStatus = gitCwd(app, 'status --porcelain');
            if ~isempty(gitStatus)
                items = strsplit(gitStatus, '\n');
                for k=1:length(items)
                    if isempty(items{k})
                        continue
                    end
                    if strcmp(items{k}(1:2), ' M')
                        result = questdlg(['Checking out previous model version will overwrite current unsaved model changes. Press cancel to save before proceeding. ' ...
                            'Proceed without saving?'], 'Discard changes?', 'Cancel', 'Discard and proceed', 'Cancel');
                        if strcmp(result, 'Cancel')
                            return
                        else
                            break
                        end
                    end
                end
            end


            % see if the selected version is the head of the master branch
            masterCommit = gitCwd(app, 'rev-parse master');
            selectedCommit = gitCwd(app, ['rev-parse ' selectedModelVersion]);
            if ~strcmp(masterCommit, selectedCommit)
                app.detachedHead = true;
                % discard changes and check out previous model
                gitCwd(app, ['checkout -f ' selectedModelVersion]);
            else 
                gitCwd(app, ['checkout -f master']);
            end
            app.setCurrentModelVersion(selectedModelVersion);
        end

        % Button pushed function: SaveModelCheckpointButton
        function SaveModelCheckpointButtonButtonPushed(app, event)
            saveModelCheckpoint(app);
        end

        % Button pushed function: SelectRepositoryDirButton
        function SelectRepositoryDirButtonButtonPushed(app, event)
            result = uigetdir(app.repositoryDir);
            if result
                app.updateRepositoryDir(result);
            end
        end

        % Value changed function: ShowGitOutputCheckBox
        function ShowGitOutputCheckBoxValueChanged(app, event)
            value = app.ShowGitOutputCheckBox.Value;
            global GIT_SHOW_OUTPUT;
            GIT_SHOW_OUTPUT = value;
                
        end

        % Value changed function: ShowallcommitsCheckBox
        function ShowallcommitsCheckBoxValueChanged(app, event)
            app.showAllCommits = app.ShowallcommitsCheckBox.Value;
            refreshArchivedModelList(app);
                
        end

        % Button pushed function: ShownotesButton
        function ShownotesButtonPushed(app, event)
            value = get(app.ArchivedModelListBox, 'Value');
            if length(value) ~= 1
                return
            end
            
            previousNotes = gitCwd(app, ['notes show ' value{1}]);
            if strfind(previousNotes, 'No note found')
                previousNotes = '';
            end
            previousNotes = strtrim(previousNotes);
            options.WindowStyle = 'normal';
            noteStr = inputdlg('Enter notes', 'Notes', [20,100], {previousNotes}, options);
                       
            if isempty(noteStr)
                return
            end
           
            notes = strjoin(arrayfun(@(k) strtrim(noteStr{1}(k,:)), 1:size(noteStr{1},1), ...
                'UniformOutput', false), '\n');
            
            gitCwd(app, sprintf('notes add -f -m "%s" %s', notes, value{1}));
        end

        % Value changed function: repositoryDirField
        function repositoryDirFieldValueChanged(app, event)
            value = app.repositoryDirField.Value;
            app.updateRepositoryDir(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Model Manager';
            app.CreateRepositoryButton = uibutton(app.UIFigure, 'push');
            app.CreateRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRepositoryButtonPushed, true);
            app.CreateRepositoryButton.Text = 'Create new model repository';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Repository directory';
            app.repositoryDirField = uieditfield(app.UIFigure, 'text');
            app.repositoryDirField.ValueChangedFcn = createCallbackFcn(app, @repositoryDirFieldValueChanged, true);
            app.SelectRepositoryDirButton = uibutton(app.UIFigure, 'push');
            app.SelectRepositoryDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRepositoryDirButtonButtonPushed, true);
            app.SelectRepositoryDirButton.Text = 'Select...';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'Current version';
            app.CurrentVersionField = uieditfield(app.UIFigure, 'text');
            app.CurrentVersionField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.CreateNewModelVersionButton = uibutton(app.Panel, 'push');
            app.CreateNewModelVersionButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModelVersionButtonButtonPushed, true);
            app.CreateNewModelVersionButton.Text = 'Create new model version';
            app.SaveModelCheckpointButton = uibutton(app.Panel, 'push');
            app.SaveModelCheckpointButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelCheckpointButtonButtonPushed, true);
            app.SaveModelCheckpointButton.Text = 'Save model';
            app.LoadSavedModelButton = uibutton(app.Panel, 'push');
            app.LoadSavedModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSavedModelButtonButtonPushed, true);
            app.LoadSavedModelButton.Text = 'Load saved model version';
            app.CompareModelsButton = uibutton(app.Panel, 'push');
            app.CompareModelsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareModelsButtonButtonPushed, true);
            app.CompareModelsButton.Text = 'Compare models';
            app.ExportmodelsButton = uibutton(app.Panel, 'push');
            app.ExportmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportmodelsButtonButtonPushed, true);
            app.ExportmodelsButton.Text = 'Export models';
            app.ImportModelsButton = uibutton(app.Panel, 'push');
            app.ImportModelsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelsButtonPushed, true);
            app.ImportModelsButton.Text = 'Import models';
            app.ShowGitOutputCheckBox = uicheckbox(app.UIFigure);
            app.ShowGitOutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGitOutputCheckBoxValueChanged, true);
            app.ShowGitOutputCheckBox.Text = 'Show git output';
            app.ShownotesButton = uibutton(app.UIFigure, 'push');
            app.ShownotesButton.ButtonPushedFcn = createCallbackFcn(app, @ShownotesButtonPushed, true);
            app.ShownotesButton.Text = 'Show notes';
            app.ShowallcommitsCheckBox = uicheckbox(app.UIFigure);
            app.ShowallcommitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowallcommitsCheckBoxValueChanged, true);
            app.ShowallcommitsCheckBox.Text = 'Show all commits ';
            app.ModeldetailsPanel = uipanel(app.UIFigure);
            app.ModeldetailsPanel.Title = 'Model details';
            app.CommitMessageTextArea = uitextarea(app.ModeldetailsPanel);
            app.CommitMessageTextArea.Editable = 'off';
            app.CommittedmodelsinrepositoryPanel = uipanel(app.UIFigure);
            app.CommittedmodelsinrepositoryPanel.Title = 'Committed models in repository';
            app.ArchivedModelListBox = uilistbox(app.CommittedmodelsinrepositoryPanel);
            app.ArchivedModelListBox.Items = {};
            app.ArchivedModelListBox.Multiselect = 'on';
            app.ArchivedModelListBox.ValueChangedFcn = createCallbackFcn(app, @ArchivedModelListBoxValueChanged, true);
            app.ArchivedModelListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModelManager

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end